classdef Final_Dice_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FirstRollButton         matlab.ui.control.Button
        FirstRollButton_2       matlab.ui.control.Button
        RollAgainButton         matlab.ui.control.Button
        RollAgainButton_2       matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        ReturntoMainMenuButton  matlab.ui.control.Button
        Image1                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image3                  matlab.ui.control.Image
        Image4                  matlab.ui.control.Image
        Image6                  matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        SlideSwitchLabel        matlab.ui.control.Label
        SlideSwitch             matlab.ui.control.Switch
        UITable                 matlab.ui.control.Table
        FARKLELabel             matlab.ui.control.Label
        ScoreEditFieldLabel     matlab.ui.control.Label
        ScoreEditField          matlab.ui.control.NumericEditField
        ScoreEditField_2Label   matlab.ui.control.Label
        ScoreEditField_2        matlab.ui.control.NumericEditField
    end

    methods (Static)
        
        function rollType = CalculateRoll(roll)
            
            numberOfOnes = length(find(roll == 1));
            numberOfTwos = length(find(roll == 2));
            numberOfThrees = length(find(roll == 3));
            numberOfFours = length(find(roll == 4));
            numberOfFives = length(find(roll == 5));
            numberOfSixes = length(find(roll == 6));
    
            Ones = 0;
            Fives = 0;
            threeOnes = 0;
            threeTwos =  0;
            threeThrees = 0;
            threeFours = 0;
            threeFives = 0;
            threeSixes = 0;
            straight = 0;
            threePairsRolled = 0;
            fourOfAKindOnePairRolled = 0;
    
            temp1 = numberOfOnes;
            temp5 = numberOfFives;
    
            for temp1 = 1:numberOfOnes
                Ones = Ones + 1;
            end
    
            for temp5 = 1:numberOfFives
                Fives = Fives + 1;
            end
    
            if numberOfOnes == 3
                threeOnes = threeOnes + 1;
            end
    
            if numberOfTwos == 3
                threeTwos = threeTwos + 1;
            end
    
            if numberOfThrees == 3
                threeThrees = threeThrees + 1;
            end
    
            if numberOfFours == 3
                threeFours = threeFours + 1;
            end
    
            if numberOfFives == 3
                threeFives = threeFives + 1;
            end
      
            if numberOfSixes == 3
                threeSixes = threeSixes + 1;
            end
    
            if numberOfOnes == 1 && numberOfTwos == 1 && numberOfThrees == 1 &&...
                numberOfFours == 1 && numberOfFives == 1 && numberOfSixes == 1
            straight = straight + 1;
            end
    
            temp = [numberOfOnes, numberOfTwos, numberOfThrees, numberOfFours, numberOfFives, numberOfSixes];
    
            threePairs = length(find(temp == 2)) == 3;
            fourOfAKindOnePair = length(find(temp == 2)) == 1 && ...
                                length(find(temp == 4)) == 1;
    
            if threePairs
                threePairsRolled = threePairsRolled + 1;
            end
    
            if fourOfAKindOnePair
                fourOfAKindOnePairRolled = fourOfAKindOnePairRolled + 1;
            end
    
            rollType = [Ones; Fives; threeOnes; threeTwos; threeThrees;...
                        threeFours; threeFives; threeSixes; straight; ...
                        threePairsRolled; fourOfAKindOnePairRolled];
            
        end
    end
    
    methods (Static)
        
        function totalScore = ComputeScore(rollType)
            scoreOnes = rollType(1)*100
            scoreFives = rollType(2)*50
            scorethreeOnes = rollType(3)*1000
            scorethreeTwos = rollType(4)*200
            scorethreeThrees = rollType(5)*300
            scorethreeFours = rollType(6)*400
            scorethreeFives = rollType(7)*500
            scorethreeSixes = rollType(8)*600
            scoreStraight = rollType(9)*3000
            scoreThreePairsRolled = rollType(10)*1500
            scoreFourOfAKindOnePairRolled = rollType(11)*1500
            totalScore = sum([scoreOnes,scoreFives,scorethreeOnes,scorethreeTwos,scorethreeThrees,scorethreeFours])
            totalScore  = sum([totalScore,scorethreeFives,scorethreeSixes])
            totalScore  = sum([totalScore,scoreStraight,scoreThreePairsRolled,scoreFourOfAKindOnePairRolled])
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.RowName = ["Ones"; "Fives"; "Three Ones"; "Three Twos"; "Three Threes";...
                "Three Fours"; "Three Fives"; "Three Sixes"; "Straight";...
                "Three Pairs"; "Four of a Kind and a Pair"]
            app.UITable.Data(:, 2) = zeros(1, 11)
            app.UITable.Data(:, 1) = zeros(1, 11)
            app.Image1.ImageSource = 'dice1.jpg'
            app.Image2.ImageSource = 'dice2.jpg'
            app.Image3.ImageSource = 'dice3.jpg'
           app.Image4.ImageSource = 'dice4.jpg'
           app.Image5.ImageSource = 'dice5.jpg'
           app.Image6.ImageSource = 'dice6.jpg'
           app.ScoreEditField.Value = 0
           
        end

        % Button pushed function: FirstRollButton
        function FirstRollButtonPushed(app, event)
            
            roll = 0;
            
            while (roll < 1)232
                
                die1 = randi(6);
                die2 = randi(6);
                die3 = randi(6);
                die4 = randi(6);
                die5 = randi(6);
                die6 = randi(6);
            
                if die1 == 1
                    app.Image1.ImageSource = 'dice1.jpg';
                elseif die1 == 2
                    app.Image1.ImageSource = 'dice2.jpg';
                elseif die1 == 3
                    app.Image1.ImageSource = 'dice3.jpg';
                elseif die1 == 4
                    app.Image1.ImageSource = 'dice4.jpg';
                elseif die1 == 5
                    app.Image1.ImageSource = 'dice5.jpg';
                elseif die1 == 6
                    app.Image1.ImageSource = 'dice6.jpg';
                end
    
                if die2 == 1
                    app.Image2.ImageSource = 'dice1.jpg';
                elseif die2 == 2
                    app.Image2.ImageSource = 'dice2.jpg';
                elseif die2 == 3
                    app.Image2.ImageSource = 'dice3.jpg';
                 elseif die2 == 4
                    app.Image2.ImageSource = 'dice4.jpg';
                 elseif die2 == 5
                    app.Image2.ImageSource = 'dice5.jpg';
                 elseif die2 == 6
                    app.Image2.ImageSource = 'dice6.jpg';
                end                    
                    
                if die3 == 1
                    app.Image3.ImageSource = 'dice1.jpg';
                 elseif die3 == 2
                    app.Image3.ImageSource = 'dice2.jpg';
                 elseif die3 == 3
                    app.Image3.ImageSource = 'dice3.jpg';
                 elseif die3 == 4
                    app.Image3.ImageSource = 'dice4.jpg';
                 elseif die3 == 5
                    app.Image3.ImageSource = 'dice5.jpg';
                 elseif die3 == 6
                    app.Image3.ImageSource = 'dice6.jpg';
                end
                
                if die4 == 1
                    app.Image4.ImageSource = 'dice1.jpg';
                 elseif die4 == 2
                    app.Image4.ImageSource = 'dice2.jpg';
                 elseif die4 == 3
                    app.Image4.ImageSource = 'dice3.jpg';
                elseif die4 == 4
                    app.Image4.ImageSource = 'dice4.jpg';
                 elseif die4 == 5
                    app.Image4.ImageSource = 'dice5.jpg';
                 elseif die4 == 6
                    app.Image4.ImageSource = 'dice6.jpg';
                end
                
                if die5 == 1
                    app.Image5.ImageSource = 'dice1.jpg';
                 elseif die5 == 2
                    app.Image5.ImageSource = 'dice2.jpg';
                 elseif die5 == 3
                    app.Image5.ImageSource = 'dice3.jpg';
                 elseif die5 == 4
                    app.Image5.ImageSource = 'dice4.jpg';
                 elseif die5 == 5
                    app.Image5.ImageSource = 'dice5.jpg';
                 elseif die5 == 6
                    app.Image5.ImageSource = 'dice6.jpg';
                end
                
                if die6 == 1
                    app.Image6.ImageSource = 'dice1.jpg';
                elseif die6 == 2
                    app.Image6.ImageSource = 'dice2.jpg';
                elseif die6 == 3
                    app.Image6.ImageSource = 'dice3.jpg';
                elseif die6 == 4
                    app.Image6.ImageSource = 'dice4.jpg';
                elseif die6 == 5
                    app.Image6.ImageSource = 'dice5.jpg';
                elseif die6 == 6
                    app.Image6.ImageSource = 'dice6.jpg';
                end
                
                roll = [die1, die2, die3, die4, die5, die6];

            rollType = app.CalculateRoll(roll);
           
            app.UITable.Data(:, 1) = rollType;
            
            totalScore = app.ComputeScore(rollType)
            
            app.ScoreEditField.Value = totalScore


            end 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4.ImageSource = 'blank.jpg';
            app.Image6.ImageSource = 'blank.jpg';
            app.Image5.ImageSource = 'blank.jpg';
        end

        % Value changed function: SlideSwitch
        function SlideSwitchValueChanged(app, event)
            
            value = app.SlideSwitch.Value;
            while value == true
                die1 = 0          
            
            
            end
        end

        % Button pushed function: FirstRollButton_2
        function FirstRollButton_2Pushed(app, event)
            roll = 0;
            
            while (roll < 1)232
                
                die1 = randi(6);
                die2 = randi(6);
                die3 = randi(6);
                die4 = randi(6);
                die5 = randi(6);
                die6 = randi(6);
            
                if die1 == 1
                    app.Image1.ImageSource = 'dice1.jpg';
                elseif die1 == 2
                    app.Image1.ImageSource = 'dice2.jpg';
                elseif die1 == 3
                    app.Image1.ImageSource = 'dice3.jpg';
                elseif die1 == 4
                    app.Image1.ImageSource = 'dice4.jpg';
                elseif die1 == 5
                    app.Image1.ImageSource = 'dice5.jpg';
                elseif die1 == 6
                    app.Image1.ImageSource = 'dice6.jpg';
                end
    
                if die2 == 1
                    app.Image2.ImageSource = 'dice1.jpg';
                elseif die2 == 2
                    app.Image2.ImageSource = 'dice2.jpg';
                elseif die2 == 3
                    app.Image2.ImageSource = 'dice3.jpg';
                 elseif die2 == 4
                    app.Image2.ImageSource = 'dice4.jpg';
                 elseif die2 == 5
                    app.Image2.ImageSource = 'dice5.jpg';
                 elseif die2 == 6
                    app.Image2.ImageSource = 'dice6.jpg';
                end                    
                    
                if die3 == 1
                    app.Image3.ImageSource = 'dice1.jpg';
                 elseif die3 == 2
                    app.Image3.ImageSource = 'dice2.jpg';
                 elseif die3 == 3
                    app.Image3.ImageSource = 'dice3.jpg';
                 elseif die3 == 4
                    app.Image3.ImageSource = 'dice4.jpg';
                 elseif die3 == 5
                    app.Image3.ImageSource = 'dice5.jpg';
                 elseif die3 == 6
                    app.Image3.ImageSource = 'dice6.jpg';
                end
                
                if die4 == 1
                    app.Image4.ImageSource = 'dice1.jpg';
                 elseif die4 == 2
                    app.Image4.ImageSource = 'dice2.jpg';
                 elseif die4 == 3
                    app.Image4.ImageSource = 'dice3.jpg';
                elseif die4 == 4
                    app.Image4.ImageSource = 'dice4.jpg';
                 elseif die4 == 5
                    app.Image4.ImageSource = 'dice5.jpg';
                 elseif die4 == 6
                    app.Image4.ImageSource = 'dice6.jpg';
                end
                
                if die5 == 1
                    app.Image5.ImageSource = 'dice1.jpg';
                 elseif die5 == 2
                    app.Image5.ImageSource = 'dice2.jpg';
                 elseif die5 == 3
                    app.Image5.ImageSource = 'dice3.jpg';
                 elseif die5 == 4
                    app.Image5.ImageSource = 'dice4.jpg';
                 elseif die5 == 5
                    app.Image5.ImageSource = 'dice5.jpg';
                 elseif die5 == 6
                    app.Image5.ImageSource = 'dice6.jpg';
                end
                
                if die6 == 1
                    app.Image6.ImageSource = 'dice1.jpg';
                elseif die6 == 2
                    app.Image6.ImageSource = 'dice2.jpg';
                elseif die6 == 3
                    app.Image6.ImageSource = 'dice3.jpg';
                elseif die6 == 4
                    app.Image6.ImageSource = 'dice4.jpg';
                elseif die6 == 5
                    app.Image6.ImageSource = 'dice5.jpg';
                elseif die6 == 6
                    app.Image6.ImageSource = 'dice6.jpg';
                end
                
                roll = [die1, die2, die3, die4, die5, die6];

            rollType = app.CalculateRoll(roll);
            
            app.UITable.Data(:, 2) = rollType;
            
            totalScore = app.ComputeScore(rollType)
            
            app.ScoreEditField_2.Value =  totalScore


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FirstRollButton = uibutton(app.UIFigure, 'push');
            app.FirstRollButton.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButtonPushed, true);
            app.FirstRollButton.Text = 'First Roll';
            app.FirstRollButton_2 = uibutton(app.UIFigure, 'push');
            app.FirstRollButton_2.ButtonPushedFcn = createCallbackFcn(app, @FirstRollButton_2Pushed, true);
            app.FirstRollButton_2.Text = 'First Roll';
            app.RollAgainButton = uibutton(app.UIFigure, 'push');
            app.RollAgainButton.Text = 'Roll Again';
            app.RollAgainButton_2 = uibutton(app.UIFigure, 'push');
            app.RollAgainButton_2.Text = 'Roll Again';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.ReturntoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainMenuButton.Text = {'Return to'; 'Main Menu'};
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = 'blank.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'blank.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'blank.jpg';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'blank.jpg';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'blank.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'blank.jpg';
            app.SlideSwitchLabel = uilabel(app.UIFigure);
            app.SlideSwitchLabel.Text = 'Slide';
            app.SlideSwitch = uiswitch(app.UIFigure, 'slider');
            app.SlideSwitch.ValueChangedFcn = createCallbackFcn(app, @SlideSwitchValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.FARKLELabel = uilabel(app.UIFigure);
            app.FARKLELabel.Text = 'FARKLE';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScoreEditField_2Label = uilabel(app.UIFigure);
            app.ScoreEditField_2Label.Text = 'Score';
            app.ScoreEditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Dice_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end